classdef mainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Easy_Assessment     matlab.ui.Figure
        b_calculate_input   matlab.ui.control.Button
        logPanel            matlab.ui.control.TextArea
        Label               matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        t_lim_prec          matlab.ui.control.NumericEditField
        t_lim_prec_label    matlab.ui.control.Label
        b_savePath          matlab.ui.control.Button
        Label_2             matlab.ui.control.Label
        b_exit              matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        tabNigth            matlab.ui.container.Tab
        dataNigth           matlab.ui.control.Table
        tabDay              matlab.ui.container.Tab
        dataDay             matlab.ui.control.Table
        tabAsNight          matlab.ui.container.Tab
        dataAssessNight     matlab.ui.control.Table
        tabAsDay            matlab.ui.container.Tab
        dataAssessDay       matlab.ui.control.Table
        b_calculate_inprog  matlab.ui.control.Button
        b_save              matlab.ui.control.Button
    end

    
    properties (Access = public)
        defPath_pub
        day_str
        night_str
        day_str_as
        night_str_as
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.logPanel.Value = 'Запуск программы';
            load([pwd, '/day.mat']);
            load([pwd, '/night.mat']);
            load([pwd, '/night_assessment.mat']);
            load([pwd, '/day_assessment.mat']);
            app.night_str = night_table;
            app.day_str = day_table;
            app.day_str_as = day_assessment;
            app.night_str_as = night_assessment;

            
            app.dataNigth.Data = night_table;            
            app.dataDay.Data = day_table;
            app.dataAssessNight.Data = night_assessment;
            app.dataAssessDay.Data = day_assessment;
            
            tableWidth = zeros(1,length(night_table(1,:)));
            tableWidth(1:end) = 90;
            tableWidth(7) = 180;
            tableWidth(19) = 180;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataNigth,'ColumnWidth', tableWidth);
            set(app.dataDay,'ColumnWidth', tableWidth);
            
            tableWidth = zeros(1,length(night_table(1,:)));
            tableWidth(1:end) = 90;
            tableWidth(9) = 120;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataAssessNight,'ColumnWidth', tableWidth);
            set(app.dataAssessDay,'ColumnWidth', tableWidth);
            
            try
                config_id = fopen([pwd, '\bin\.cfg'], 'r');
                app.defPath_pub = textscan(config_id, '%s');
                fclose(config_id);
                if ~isempty(app.defPath_pub{1})
                    temp = app.defPath_pub{1};
                    app.defPath_pub = temp{1};
                end
            catch
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при открытии файла конфигурации'];
            end
            
            if length(app.defPath_pub) < 2
                app.logPanel.Value = [app.logPanel.Value; 'Путь по умолчанию не задан'];
            else
                app.logPanel.Value = [app.logPanel.Value; strcat('Путь по умолчанию: ',app.defPath_pub)];
            end
        end

        % Close request function: Easy_Assessment
        function Easy_AssessmentCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: b_calculate_input
        function b_calculate_inputButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,'Title','Пожалуйста подождите',...
                'Message','Загрузка...','Indeterminate','on');
            [file,path] = uigetfile('*.xlsx', 'Выберите файл');
            if length(file) > 1
                fPath = [path,file];
                try
                    % =============================== night ===================================
                    % =========================================================================
                    [~,~,fact_t_n] = xlsread(fPath, 1,'C5:C67');
                    [~,~,forecast_t_n] = xlsread(fPath, 1,'D5:D67');
                    t_night = temperature(fact_t_n, forecast_t_n);
                    
                    [~,~,fact_p_n] = xlsread(fPath, 1,'F5:F67');
                    [~,~,forecast_p_n] = xlsread(fPath, 1,'G5:G67');
                    p_night = precipitation(fact_p_n, forecast_p_n, fact_t_n, app.t_lim_prec.Value);
                    
                    %==================== phenomenons =========================================
                    [~,~,forecast_wind_n] = xlsread(fPath, 1,'I5:I67');
                    [~,~,fact_wind_n] = xlsread(fPath, 1,'U5:U67');
                    wind_night = wind_func(fact_wind_n, forecast_wind_n);
                    
                    [~,~,phen_fore_n] = xlsread(fPath, 1,'J5:T67');
                    [~,~,phen_fact_n] = xlsread(fPath, 1,'V5:AF67');
                    assessment_night = phen_assessment(wind_night, phen_fore_n, phen_fact_n);
                    
                    % =============================== day =====================================
                    % =========================================================================
                    
                    [~,~,fact_t_d] = xlsread(fPath, 1,'AJ5:AJ67');
                    [~,~,forecast_t_d] = xlsread(fPath, 1,'AK5:AK67');
                    t_day = temperature(fact_t_d, forecast_t_d);
                    
                    [~,~,fact_p_d] = xlsread(fPath, 1,'AM5:AM67');
                    [~,~,forecast_p_d] = xlsread(fPath, 1,'AN5:AN67');
                    p_day = precipitation(fact_p_d, forecast_p_d, fact_t_d, app.t_lim_prec.Value);
                    
                    %==================== phenomenons =========================================
                    [~,~,fact_wind_d] = xlsread(fPath, 1,'AP5:AP67');
                    [~,~,forecast_wind_d] = xlsread(fPath, 1,'BB5:BB67');
                    wind_day = wind_func(fact_wind_d, forecast_wind_d);
                    
                    [~,~,phen_fore_d] = xlsread(fPath, 1,'AQ5:BA67');
                    [~,~,phen_fact_d] = xlsread(fPath, 1,'BC5:BM67');
                    assessment_day = phen_assessment(wind_day, phen_fore_d, phen_fact_d);
                catch
                    d.close();
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка работы Microsoft Excel:...'];
                    app.logPanel.Value = [app.logPanel.Value; 'не удалось загрузить входной файл'];
                end
                
                % ============== night_output =============================================
                % =========================================================================
                if length(app.defPath_pub) < 2
                    out_dir = uigetdir(pwd, 'Выберите путь для сохранения файла');
                else
                    out_dir = app.defPath_pub;
                end
                if length(out_dir) > 2
                    try
                        copyfile('bin\оценка_прогнозирования.xlsx', out_dir);
                    catch
                        app.logPanel.Value = [app.logPanel.Value; 'Ошибка копирования образца выходного файла'];                        
                    end
                    
                    try
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_t_n, 1, 'C5:C67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_t_n, 1, 'D5:D67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], t_night, 1, 'E5:E67');
                        
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_p_n, 1, 'F5:F67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_p_n, 1, 'G5:G67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], p_night, 1, 'H5:H67');
                        
                        % =============== phenomenon assessment night ==============
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_wind_n, 1, 'I5:I67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_wind_n, 1, 'U5:U67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fact_n, 1, 'J5:T67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fore_n, 1, 'V5:AF67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], assessment_night, 1, 'AG5:AG67');
                        
                        % ============= day_output ================================================
                        % =========================================================================
                        
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_t_d, 2, 'C5:C67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_t_d, 2, 'D5:D67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], t_day, 2, 'E5:E67');
                        
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_p_d, 2, 'F5:F67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_p_d, 2, 'G5:G67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], p_day, 2, 'H5:H67');
                        
                        % =============== phenomenon assessment day ==============
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_wind_d, 2, 'I5:I67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_wind_d, 2, 'U5:U67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fact_d, 2, 'J5:T67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fore_d, 2, 'V5:AF67');
                        xlswrite([out_dir,'оценка_прогнозирования.xlsx'], assessment_day, 2, 'AG5:AG67');
                        d.close();
                        uialert(app.Easy_Assessment, 'Операция завершена', 'Сохранение файла', 'Icon','info');
                        app.logPanel.Value = [app.logPanel.Value; 'Файл сохранен'];
                    catch
                        d.close();
                        app.logPanel.Value = [app.logPanel.Value; 'Ошибка работы Microsoft Excel:...'];
                        app.logPanel.Value = [app.logPanel.Value; 'не удалось сохранить выходной файл'];
                    end
                else
                    d.close();
                    uialert(app.Easy_Assessment, 'Ошибка при сохранении файла', 'Ошибка', 'Icon','error');
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка при сохранении файла'];
                end
            else
                d.close();
                uialert(app.Easy_Assessment, 'Ошибка при загрузке файла', 'Ошибка', 'Icon','error');
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при загрузке файла'];
            end
        end

        % Button pushed function: b_savePath
        function b_savePathButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,...
                'Message','Получение пути','Indeterminate','on');
            dir = uigetdir(pwd, 'Выберите путь по умолчанию');
            if length(dir) > 2
                try
                    user_conf_id = fopen([pwd, '\bin\.cfg'], 'w');
                    fprintf(user_conf_id, '%s' , dir);
                    fclose(user_conf_id);
                    d.close();
                    uialert(app.Easy_Assessment, 'Путь по умолчанию для выходного файли изменен', 'Информация', 'Icon','info');
                    app.logPanel.Value = [app.logPanel.Value; 'Путь по умолчанию для выходного файли изменен'];
                    app.logPanel.Value = [app.logPanel.Value; strcat('Новый путь: ', dir)];
                catch
                    d.close();
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка при попытке задать путь по умолчанию'];
                end
            else
                d.close();
                app.logPanel.Value = [app.logPanel.Value; 'Операция отменена'];
            end
            
        end

        % Button pushed function: b_exit
        function b_exitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: b_calculate_inprog
        function b_calculate_inprogButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,'Title','Пожалуйста подождите',...
                'Message','Загрузка...','Indeterminate','on');
            try
                fact_t_n = app.dataNigth.Data(3:end, 3);
                forecast_t_n = app.dataNigth.Data(3:end, 4);
                t_night = temperature(fact_t_n, forecast_t_n);
                
                fact_p_n = app.dataNigth.Data(3:end, 5);
                forecast_p_n = app.dataNigth.Data(3:end, 6);
                p_night = precipitation(fact_p_n, forecast_p_n, fact_t_n, app.t_lim_prec.Value);
                
                %==================== phenomenons =========================================
                forecast_wind_n = app.dataNigth.Data(3:end, 7);
                fact_wind_n = app.dataNigth.Data(3:end, 19);
                wind_night = wind_func(fact_wind_n, forecast_wind_n);
                
                phen_fore_n = app.dataNigth.Data(3:end, 8:18);
                phen_fact_n = app.dataNigth.Data(3:end, 20:30);
                assessment_night = phen_assessment(wind_night, phen_fore_n, phen_fact_n);
                
                % =============================== day =====================================
                % =========================================================================
                
                fact_t_d = app.dataDay.Data(3:end, 3);
                forecast_t_d = app.dataDay.Data(3:end, 4);
                t_day = temperature(fact_t_d, forecast_t_d);
                
                fact_p_d = app.dataDay.Data(3:end, 5);
                forecast_p_d = app.dataDay.Data(3:end, 6);
                p_day = precipitation(fact_p_d, forecast_p_d, fact_t_d, app.t_lim_prec.Value);
                
                %==================== phenomenons =========================================
                forecast_wind_d = app.dataDay.Data(3:end, 7);
                fact_wind_d = app.dataDay.Data(3:end, 19);
                wind_day = wind_func(fact_wind_d, forecast_wind_d);
                
                phen_fore_d = app.dataDay.Data(3:end, 8:18);
                phen_fact_d = app.dataDay.Data(3:end, 20:30);
                assessment_day = phen_assessment(wind_day, phen_fore_d, phen_fact_d);
            catch
                d.close();
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка рассчета программной таблицы'];
            end  
            
            try
                app.dataAssessNight.Data(4:end,3) = fact_t_n;
                app.dataAssessNight.Data(4:end,4) = forecast_t_n;
                app.dataAssessNight.Data(4:end,5) = t_night;
                
                app.dataAssessNight.Data(4:end,6) = fact_p_n;
                app.dataAssessNight.Data(4:end,7) = forecast_p_n;
                app.dataAssessNight.Data(4:end,8) = p_night;
                
                
                % =============== phenomenon assessment night ==============
                app.dataAssessNight.Data(4:end,9) = fact_wind_n;
                app.dataAssessNight.Data(4:end,21) = forecast_wind_n;
                app.dataAssessNight.Data(4:end,10:20) = phen_fact_n;
                app.dataAssessNight.Data(4:end,22:32) = phen_fore_n;
                app.dataAssessNight.Data(4:end,33) = num2cell(assessment_night);
                
                % ============= day_output ================================================
                % =========================================================================
                app.dataAssessDay.Data(4:end,3) = fact_t_d;
                app.dataAssessDay.Data(4:end,4) = forecast_t_d;
                app.dataAssessDay.Data(4:end,5) = t_day;
                
                app.dataAssessDay.Data(4:end,6) = fact_p_d;
                app.dataAssessDay.Data(4:end,7) = forecast_p_d;
                app.dataAssessDay.Data(4:end,8) = p_day;
                
                
                % =============== phenomenon assessment night ==============
                app.dataAssessDay.Data(4:end,9) = fact_wind_d;
                app.dataAssessDay.Data(4:end,21) = forecast_wind_d;
                app.dataAssessDay.Data(4:end,10:20) = phen_fact_d;
                app.dataAssessDay.Data(4:end,22:32) = phen_fore_d;
                app.dataAssessDay.Data(4:end,33) = num2cell(assessment_day);
                d.close();
                uialert(app.Easy_Assessment, 'Операция завершена', 'Сохранение файла', 'Icon','info');
                app.logPanel.Value = [app.logPanel.Value; 'Файл сохранен'];
            catch
                d.close();
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка работы Microsoft Excel:...'];
                app.logPanel.Value = [app.logPanel.Value; 'не удалось сохранить выходной файл'];
            end
        end

        % Button pushed function: b_save
        function b_saveButtonPushed(app, event)
            if length(app.defPath_pub) < 2
                out_dir = uigetdir(pwd, 'Выберите путь для сохранения файла');
            else
                out_dir = app.defPath_pub;
            end
            
            if length(out_dir) > 2
                try
                    copyfile('bin\оценка_прогнозирования.xlsx', out_dir);
                catch
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка копирования образца выходного файла'];
                end
                
                try
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_t_n, 1, 'C5:C67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_t_n, 1, 'D5:D67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], t_night, 1, 'E5:E67');
                    
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_p_n, 1, 'F5:F67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_p_n, 1, 'G5:G67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], p_night, 1, 'H5:H67');
                    
                    % =============== phenomenon assessment night ==============
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_wind_n, 1, 'I5:I67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_wind_n, 1, 'U5:U67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fact_n, 1, 'J5:T67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fore_n, 1, 'V5:AF67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], assessment_night, 1, 'AG5:AG67');
                    
                    % ============= day_output ================================================
                    % =========================================================================
                    
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_t_d, 2, 'C5:C67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_t_d, 2, 'D5:D67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], t_day, 2, 'E5:E67');
                    
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_p_d, 2, 'F5:F67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_p_d, 2, 'G5:G67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], p_day, 2, 'H5:H67');
                    
                    % =============== phenomenon assessment day ==============
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], fact_wind_d, 2, 'I5:I67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], forecast_wind_d, 2, 'U5:U67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fact_d, 2, 'J5:T67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], phen_fore_d, 2, 'V5:AF67');
                    xlswrite([out_dir,'оценка_прогнозирования.xlsx'], assessment_day, 2, 'AG5:AG67');
                    d.close();
                    uialert(app.Easy_Assessment, 'Операция завершена', 'Сохранение файла', 'Icon','info');
                    app.logPanel.Value = [app.logPanel.Value; 'Файл сохранен'];
                catch
                    d.close();
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка работы Microsoft Excel:...'];
                    app.logPanel.Value = [app.logPanel.Value; 'не удалось сохранить выходной файл'];
                end
            else
                d.close();
                uialert(app.Easy_Assessment, 'Ошибка при сохранении файла', 'Ошибка', 'Icon','error');
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при сохранении файла'];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Easy_Assessment = uifigure;
            app.Easy_Assessment.Name = 'Easy assessment';
            app.Easy_Assessment.CloseRequestFcn = createCallbackFcn(app, @Easy_AssessmentCloseRequest, true);
            app.b_calculate_input = uibutton(app.Easy_Assessment, 'push');
            app.b_calculate_input.ButtonPushedFcn = createCallbackFcn(app, @b_calculate_inputButtonPushed, true);
            app.b_calculate_input.Text = {'Расчитать по '; 'excel файлу'};
            app.logPanel = uitextarea(app.Easy_Assessment);
            app.logPanel.Editable = 'off';
            app.Label = uilabel(app.Easy_Assessment);
            app.Label.Text = 'Журнал работы программы';
            app.Panel = uipanel(app.Easy_Assessment);
            app.Panel.Title = 'Дополнительные параметры программы';
            app.t_lim_prec = uieditfield(app.Panel, 'numeric');
            app.t_lim_prec.Limits = [-50 50];
            app.t_lim_prec.Value = -5;
            app.t_lim_prec_label = uilabel(app.Panel);
            app.t_lim_prec_label.Text = {'Пороговая t° '; 'для осадков'};
            app.b_savePath = uibutton(app.Panel, 'push');
            app.b_savePath.ButtonPushedFcn = createCallbackFcn(app, @b_savePathButtonPushed, true);
            app.b_savePath.Text = 'Сохранить...';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = {'Задать/изменить '; 'путь по умолчанию'};
            app.b_exit = uibutton(app.Easy_Assessment, 'push');
            app.b_exit.ButtonPushedFcn = createCallbackFcn(app, @b_exitButtonPushed, true);
            app.b_exit.Text = 'Закрыть';
            app.TabGroup = uitabgroup(app.Easy_Assessment);
            app.tabNigth = uitab(app.TabGroup);
            app.tabNigth.Title = 'Ночь';
            app.dataNigth = uitable(app.tabNigth);
            app.dataNigth.ColumnName = '';
            app.dataNigth.RowName = {};
            app.dataNigth.ColumnEditable = true;
            app.tabDay = uitab(app.TabGroup);
            app.tabDay.Title = 'День';
            app.dataDay = uitable(app.tabDay);
            app.dataDay.ColumnName = '';
            app.dataDay.RowName = {};
            app.dataDay.ColumnEditable = true;
            app.tabAsNight = uitab(app.TabGroup);
            app.tabAsNight.Title = 'Оценка ночь';
            app.dataAssessNight = uitable(app.tabAsNight);
            app.dataAssessNight.ColumnName = '';
            app.dataAssessNight.RowName = {};
            app.dataAssessNight.ColumnEditable = true;
            app.tabAsDay = uitab(app.TabGroup);
            app.tabAsDay.Title = 'Оценка день';
            app.dataAssessDay = uitable(app.tabAsDay);
            app.dataAssessDay.ColumnName = '';
            app.dataAssessDay.RowName = {};
            app.dataAssessDay.ColumnEditable = true;
            app.b_calculate_inprog = uibutton(app.Easy_Assessment, 'push');
            app.b_calculate_inprog.ButtonPushedFcn = createCallbackFcn(app, @b_calculate_inprogButtonPushed, true);
            app.b_calculate_inprog.Text = {'Расчитать по '; 'таблице программы'};
            app.b_save = uibutton(app.Easy_Assessment, 'push');
            app.b_save.ButtonPushedFcn = createCallbackFcn(app, @b_saveButtonPushed, true);
            app.b_save.Text = 'Сохранить';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainWindow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Easy_Assessment)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Easy_Assessment)
        end
    end
end